classdef GDD_TOD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        fsLabel                      matlab.ui.control.Label
        fs3Label                     matlab.ui.control.Label
        fs2Label                     matlab.ui.control.Label
        EmodVal                      matlab.ui.control.NumericEditField
        PulselenVal                  matlab.ui.control.NumericEditField
        EmodSlider                   matlab.ui.control.Slider
        EnergymodulationSliderLabel  matlab.ui.control.Label
        PulselenSlider               matlab.ui.control.Slider
        PulselengthSliderLabel       matlab.ui.control.Label
        TODSlider                    matlab.ui.control.Slider
        TODSliderLabel               matlab.ui.control.Label
        GDDSlider                    matlab.ui.control.Slider
        GDDSliderLabel               matlab.ui.control.Label
        TODval                       matlab.ui.control.NumericEditField
        GDDval                       matlab.ui.control.NumericEditField
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function CalcSpec(app)
            phi2 = app.GDDSlider.Value;
            phi3 = app.TODSlider.Value;
            Emod = app.EmodSlider.Value/100;
            t0   = app.PulselenSlider.Value*1e-15;

            %Emod = 0.0060;
            %t0 = 45e-15;
            c0=2.9979e8;
            sigmae = 0.0007;
            bwd = sqrt(2)*0.441/t0;
            sigf = bwd/2.355;
            wl0 = 800e-9;
            f0 = c0/wl0;
            w0 = 2*pi*f0;
            fi = f0+50e12;
            fe = f0-50e12;
            ff = linspace(fi,fe,401);
            ww = 2*pi*ff;
            Ampf = exp(-(ff-f0).^2/2/sigf^2);

            D2 = phi2*1e-30;
            D3 = phi3*1e-45;
            Phi = 1/2*D2*(ww-w0).^2 + D3*(ww-w0).^3;
            
            tt= -600e-15:0.005e-15:600e-15;
            pul = zeros(1,length(tt));
            for i=1:length(ff)
                pul= pul + Ampf(i)*sin(2*pi*ff(i)*tt+Phi(i));
            end
            pul= pul/max(pul);
            
%             [pks,loc] = findpeaks(pul.^2);
%             fit_p = fit(tt(loc).'*1e15,pks.','gauss1');
%             fwhm = fit_p.c1/sqrt(2)*2.355;
            
            zz0 = tt*c0;
            dE = normrnd(0,sigmae,1,length(zz0));
            dE = dE+pul*Emod;
            
            r56_l = linspace(0,130e-6,131);
            spec_l = [];
            for i=1:length(r56_l)
                r56 = r56_l(i);
                zz = zz0+ dE*r56;
                dens = histcounts(zz,8001);
                spec = abs(fftshift(fft(dens))).^2;
                
                T = (max(tt)-min(tt))/length(dens);
                Fs = 1/T;
                L = length(dens);
                dF = Fs/L;
                freq = -Fs/2:dF:Fs/2-dF;
                f2i = floor((7.1379e14-min(freq))/dF);
                f2l = floor((7.92e14-min(freq))/dF);
                spec_l(i,:) = spec(f2i:f2l);
            end
            Spec = spec_l;
        wl = c0./freq(f2i:f2l)*1e9;
        app.UIAxes.View = [0 90];
        contourf(app.UIAxes,wl,r56_l*1e6,Spec,100,'LineColor','none');
        plot(app.UIAxes2,tt*1e15,pul);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CalcSpec(app);
        end

        % Value changed function: GDDSlider
        function GDDSliderValueChanged(app, event)
            CalcSpec(app);
            app.GDDval.Value = app.GDDSlider.Value;
        end

        % Value changed function: TODSlider
        function TODSliderValueChanged(app, event)
            CalcSpec(app);
            app.TODval.Value = app.TODSlider.Value;
        end

        % Value changed function: GDDval
        function GDDvalValueChanged(app, event)
            app.GDDSlider.Value = app.GDDval.Value;
            CalcSpec(app);
        end

        % Value changed function: TODval
        function TODvalValueChanged(app, event)
            app.TODSlider.Value = app.TODval.Value;
            CalcSpec(app);
        end

        % Value changed function: PulselenSlider
        function PulselenSliderValueChanged(app, event)
            app.PulselenVal.Value = app.PulselenSlider.Value;
            CalcSpec(app);
        end

        % Value changed function: EmodSlider
        function EmodSliderValueChanged(app, event)
            app.EmodVal.Value = app.EmodSlider.Value;
            CalcSpec(app);
        end

        % Value changed function: EmodVal
        function EmodValValueChanged(app, event)
            app.EmodSlider.Value = app.EmodVal.Value;
            CalcSpec(app);
        end

        % Value changed function: PulselenVal
        function PulselenValValueChanged(app, event)
            app.PulselenSlider.Value = app.PulselenVal.Value;
            CalcSpec(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CHG Spectral Map')
            xlabel(app.UIAxes, '\lambda (nm)')
            ylabel(app.UIAxes, 'R_{56} (\mum )')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [380 420];
            app.UIAxes.YLim = [0 130];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'E-field')
            xlabel(app.UIAxes2, 't (fs)')
            ylabel(app.UIAxes2, 'E')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-600 600];
            app.UIAxes2.YLim = [-1.1 1.1];
            app.GDDval = uieditfield(app.UIFigure, 'numeric');
            app.GDDval.ValueChangedFcn = createCallbackFcn(app, @GDDvalValueChanged, true);
            app.TODval = uieditfield(app.UIFigure, 'numeric');
            app.TODval.ValueChangedFcn = createCallbackFcn(app, @TODvalValueChanged, true);
            app.GDDSliderLabel = uilabel(app.UIFigure);
            app.GDDSliderLabel.Text = 'GDD';
            app.GDDSlider = uislider(app.UIFigure);
            app.GDDSlider.Limits = [-6000 6000];
            app.GDDSlider.ValueChangedFcn = createCallbackFcn(app, @GDDSliderValueChanged, true);
            app.TODSliderLabel = uilabel(app.UIFigure);
            app.TODSliderLabel.Text = 'TOD';
            app.TODSlider = uislider(app.UIFigure);
            app.TODSlider.Limits = [-30000 30000];
            app.TODSlider.ValueChangedFcn = createCallbackFcn(app, @TODSliderValueChanged, true);
            app.PulselengthSliderLabel = uilabel(app.UIFigure);
            app.PulselengthSliderLabel.Text = 'Pulse length';
            app.PulselenSlider = uislider(app.UIFigure);
            app.PulselenSlider.Limits = [10 210];
            app.PulselenSlider.ValueChangedFcn = createCallbackFcn(app, @PulselenSliderValueChanged, true);
            app.PulselenSlider.Value = 45;
            app.EnergymodulationSliderLabel = uilabel(app.UIFigure);
            app.EnergymodulationSliderLabel.Text = {'Energy'; 'modulation'};
            app.EmodSlider = uislider(app.UIFigure);
            app.EmodSlider.Limits = [0 0.9];
            app.EmodSlider.ValueChangedFcn = createCallbackFcn(app, @EmodSliderValueChanged, true);
            app.EmodSlider.Value = 0.6;
            app.PulselenVal = uieditfield(app.UIFigure, 'numeric');
            app.PulselenVal.ValueChangedFcn = createCallbackFcn(app, @PulselenValValueChanged, true);
            app.PulselenVal.Value = 45;
            app.EmodVal = uieditfield(app.UIFigure, 'numeric');
            app.EmodVal.ValueChangedFcn = createCallbackFcn(app, @EmodValValueChanged, true);
            app.EmodVal.Value = 0.6;
            app.fs2Label = uilabel(app.UIFigure);
            app.fs2Label.Interpreter = 'latex';
            app.fs2Label.Text = 'fs$^2$';
            app.fs3Label = uilabel(app.UIFigure);
            app.fs3Label.Interpreter = 'latex';
            app.fs3Label.Text = 'fs$^3$';
            app.fsLabel = uilabel(app.UIFigure);
            app.fsLabel.Text = 'fs';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GDD_TOD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end